classdef Cifrado_cesar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        IdiomaButtonGroup             matlab.ui.container.ButtonGroup
        EnglishButton                 matlab.ui.control.RadioButton
        SpanishlButton                matlab.ui.control.RadioButton
        TextoClaroEditField           matlab.ui.control.EditField
        TextoClaroEditFieldLabel      matlab.ui.control.Label
        EncriptarButton               matlab.ui.control.StateButton
        EntradasLabel                 matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        SalidaLabel                   matlab.ui.control.Label
        TextoEncriptadoTextArea       matlab.ui.control.TextArea
        TextoEncriptadoTextAreaLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EncriptarButton
        function EncriptarButtonValueChanged(app, event)
            value = app.EncriptarButton.Value;
            %-------- INPUT -----------
            texto_claro = app.TextoClaroEditField.Value;  
            spanish= app.SpanishlButton.Value;
            english=app.EnglishButton.Value;
          

            %------------- PROCESS  ------------
            ascii = double(texto_claro);
            if (english== 1)
                for n=1:length(texto_claro)
                    if (ascii(n)==double('X'))
                        ascii(n) = double('A') - 3;
                    elseif (ascii(n)==double('Y'))
                        ascii(n) = double('B') - 3;
                    elseif (ascii(n)==double('Z'))
                        ascii(n) = double('C') - 3;
                    elseif (ascii(n)==double(' '))
                        ascii(n) = double(' ') - 3;
                    elseif (ascii(n)==double('x'))
                        ascii(n) = double('a') - 3;
                    elseif (ascii(n)==double('y'))
                        ascii(n) = double('b') - 3;
                    elseif (ascii(n)==double('z'))
                        ascii(n) = double('c') - 3;
                    end
                end
            elseif (spanish==1)
                for n=1:length(texto_claro)
                    if (ascii(n)==double('X'))
                        ascii(n) = double('A') - 3;
                    elseif (ascii(n)==double('Y'))
                        ascii(n) = double('B') - 3;
                    elseif (ascii(n)==double('Z'))
                        ascii(n) = double('C') - 3;
                    elseif (ascii(n)==double(' '))
                        ascii(n) = double(' ') - 3;
                    elseif (ascii(n)==double('x'))
                        ascii(n) = double('a') - 3;
                    elseif (ascii(n)==double('y'))
                        ascii(n) = double('b') - 3;
                    elseif (ascii(n)==double('z'))
                        ascii(n) = double('c') - 3;
                    elseif (ascii(n)==double('L'))
                        ascii(n) = double('Ñ') - 3;
                    elseif (ascii(n)==double('M'))
                        ascii(n) = double('O') - 3;
                    elseif (ascii(n)==double('N'))
                        ascii(n) = double('P') - 3;
                    elseif (ascii(n)==double('l'))
                        ascii(n) = double('ñ') - 3;
                    elseif (ascii(n)==double('m'))
                        ascii(n) = double('o') - 3;
                    elseif (ascii(n)==double('n'))
                        ascii(n) = double('p') - 3;
                    elseif (ascii(n)==double('ñ'))
                        ascii(n) = double('q') - 3;
                    elseif (ascii(n)==double('Ñ'))
                        ascii(n) = double('Q') - 3;
                    end
                end
           end
           cesar = ascii + 3;
           criptograma = char(cesar);


            %-------- OUTPUT -----------
            app.TextoEncriptadoTextArea.Value= criptograma
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {361, 361};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {409, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EntradasLabel = uilabel(app.LeftPanel);
            app.EntradasLabel.Text = 'Entradas';
            app.EncriptarButton = uibutton(app.LeftPanel, 'state');
            app.EncriptarButton.ValueChangedFcn = createCallbackFcn(app, @EncriptarButtonValueChanged, true);
            app.EncriptarButton.Text = 'Encriptar';
            app.TextoClaroEditFieldLabel = uilabel(app.LeftPanel);
            app.TextoClaroEditFieldLabel.Text = 'Texto Claro';
            app.TextoClaroEditField = uieditfield(app.LeftPanel, 'text');
            app.IdiomaButtonGroup = uibuttongroup(app.LeftPanel);
            app.IdiomaButtonGroup.Title = 'Idioma';
            app.SpanishlButton = uiradiobutton(app.IdiomaButtonGroup);
            app.SpanishlButton.Text = 'Español';
            app.SpanishlButton.Value = true;
            app.EnglishButton = uiradiobutton(app.IdiomaButtonGroup);
            app.EnglishButton.Text = 'Inglés';
            app.RightPanel = uipanel(app.GridLayout);
            app.TextoEncriptadoTextAreaLabel = uilabel(app.RightPanel);
            app.TextoEncriptadoTextAreaLabel.Text = 'Texto Encriptado';
            app.TextoEncriptadoTextArea = uitextarea(app.RightPanel);
            app.SalidaLabel = uilabel(app.RightPanel);
            app.SalidaLabel.Text = 'Salida';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cifrado_cesar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end