classdef Vernam_app < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TextoEncriptadoTextArea       matlab.ui.control.TextArea
        TextoEncriptadoTextAreaLabel  matlab.ui.control.Label
        TextoClaroEditField           matlab.ui.control.EditField
        TextoClaroEditFieldLabel      matlab.ui.control.Label
        EncriptarButton               matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EncriptarButton
        function EncriptarButtonValueChanged(comp, event)
            value = comp.EncriptarButton.Value

        %----------INPUT---------------------
        texto = comp.TextoClaroEditField.Value;
        
        %----------PROCESS---------------------
        
        clave = randi([0 1], length(texto),8);
        ascii = double(texto);
        bits = dec2bin(ascii);
        
        for n=1:length(texto)
            bytes(n,:) = cat(2,'0', bits(n,:));
        end
        
        for filas=1:length(texto)
           for columnas=1:8 
                bits_num(filas, columnas) = str2num(bytes(filas, columnas));
           end
        end
        
        %----------OUTPUT---------------------
        
        cipher_matrix = bitxor(bits_num, clave);
        cipher_matrix_binario = num2str(cipher_matrix);
        cipher_matrix_binario(cipher_matrix_binario == ' ') = '';
        comp.TextoEncriptadoTextArea.Value = cipher_matrix_binario;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 320 240];
            comp.BackgroundColor = [0.8 0.8 0.8];
            comp.EncriptarButton = uibutton(comp, 'state');
            comp.EncriptarButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EncriptarButtonValueChanged, true);
            comp.EncriptarButton.Text = 'Encriptar';
            comp.TextoClaroEditFieldLabel = uilabel(comp);
            comp.TextoClaroEditFieldLabel.Text = 'Texto Claro';
            comp.TextoClaroEditField = uieditfield(comp, 'text');
            comp.TextoEncriptadoTextAreaLabel = uilabel(comp);
            comp.TextoEncriptadoTextAreaLabel.Text = 'Texto Encriptado';
            comp.TextoEncriptadoTextArea = uitextarea(comp);
        end
    end
end